classdef PDProfiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        ApplicationLogLabel             matlab.ui.control.Label
        VersionLabel                    matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        SaveNormalizationButton         matlab.ui.control.Button
        Step4Instructions               matlab.ui.control.Label
        Step4Label                      matlab.ui.control.Label
        BrowseMeasuredButton            matlab.ui.control.Button
        MeasuredImageEditField          matlab.ui.control.EditField
        MeasuredImageEditFieldLabel     matlab.ui.control.Label
        BrowsePlannedButton             matlab.ui.control.Button
        PlannedDoseImageEditField       matlab.ui.control.EditField
        PlannedDoseImageEditFieldLabel  matlab.ui.control.Label
        BrowseNormalizationButton       matlab.ui.control.Button
        NormalizationProfileEditField   matlab.ui.control.EditField
        NormalizationProfileEditFieldLabel  matlab.ui.control.Label
        Step3Label                      matlab.ui.control.Label
        Step2Instructions               matlab.ui.control.Label
        DownloadDICOMButton             matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        Step1Label                      matlab.ui.control.Label
        Copyright                       matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        path % Description
        info % Description
        currentCalibration % Description
        targetProfile % Description
        measuredImage % Description
        measuredProfile % Description
        newProfile % Description
        plotLegend % Description
    end
    
    methods (Access = private)
        
        % This function calculates the new, corrected file if all inputs
        % have been defined
        function CalculateCorrectedProfile(app)
            
            % If all three inputs are loaded
            if ~isempty(app.currentCalibration) && ~isempty(app.targetProfile) ...
                    && ~isempty(app.measuredProfile)

                % Calculate new calibration profile from target/measured profile difference
                app.newProfile = app.currentCalibration;
                app.newProfile(:,end) = app.currentCalibration(:,end) .* interp1(app.targetProfile(:,1), ...
                    app.targetProfile(:,end), sqrt(app.currentCalibration(:,1).^2 + ...
                    app.currentCalibration(:,2).^2), 'linear', app.targetProfile(end,end)) ./ ...
                    interp1(app.measuredProfile(:,1), app.measuredProfile(:,end), ...
                    sqrt(app.currentCalibration(:,1).^2 + app.currentCalibration(:,2).^2), 'linear', ...
                    app.targetProfile(end,end));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Define version number
            version = '1.0.0';
            set(app.VersionLabel, 'Text', ['Version ', version]);

            % Determine path of current application
            [path, ~, ~] = fileparts(mfilename('fullpath')); %#ok<*ADPROP> 

            % Set current directory to location of this application
            cd(path);
            app.path = path;

            % Get MATLAB/OS versions, see LoadVersionInfo for more details
            app.info = LoadVersionInfo;

            % Store program and MATLAB/etc version information as a string cell array
            string = {'PDProfiler Panel Normalization Correction Tool'
                sprintf('Version: %s (%s)', version, app.info{6});
                sprintf('Author: Mark Geurts <mark.w.geurts@gmail.com>');
                sprintf('MATLAB Version: %s', app.info{2});
                sprintf('MATLAB License Number: %s', app.info{3});
                sprintf('Operating System: %s', app.info{1});
                sprintf('CUDA: %s', app.info{4});
                sprintf('Java Version: %s', app.info{5})
            };

            % Add dashed line separators
            separator = repmat('-', 1,  size(char(string), 2));
            string = sprintf('%s\n', separator, string{:}, separator);
    
            % Initialize data structures
            app.currentCalibration = [];
            app.targetProfile = [];
            app.measuredImage = [];
            app.measuredProfile = [];
            app.newProfile = [];

            % Initialize plots
            set(app.UIAxes, 'Visible', false);
            app.plotLegend = {'Current Normalization', ...
                    'Planned Open Field Profile', ...
                    'Measured Open Field Profile', ...
                    'Corrected Normalization'};
            set(app.UIAxes_2, 'Visible', false);

            % Log start
            Event(string, 'INIT', app.UITable);
            Event('PDProfiler GUI initialization completed successfully');

            % Clean up
            clear path separator string;
        end

        % Button pushed function: DownloadDICOMButton
        function DownloadDICOMButtonPushed(app, event)

        end

        % Button pushed function: BrowseNormalizationButton
        function BrowseNormalizationButtonPushed(app, event)
            
            % Request the user to select the normalization file
            Event('UI window opened to select normalization file');
            [name, path] = uigetfile({'*.cdp', 'W2CAD File (*.cdp)'}, ...
                'Select the Normalization', app.path); %#ok<*ADPROPLC> 

            % If the user selected a file
            if ~isequal(name, 0)

                % Log event
                Event(['User selected ', fullfile(path, name)]);

                % Update default path
                Event(['Default file path updated to ', path]);
                app.path = path;

                % Set UI field with filename
                set(app.NormalizationProfileEditField, 'Value', name);

                % Load file
                app.currentCalibration = ...
                    sortrows(LoadCDP(fullfile(path, name)));

                % Calculate corrected profile, if able
                CalculateCorrectedProfile(app);

                % Update profile plot
                PlotNormalizedProfiles({app.currentCalibration, ...
                    app.targetProfile, ...
                    app.measuredProfile, ...
                    app.newProfile}, ...
                    app.UIAxes_2, 'first');
                set(app.UIAxes_2, 'Visible', true);
                legend(app.UIAxes_2, app.plotLegend, 'Location', 'southwest');

            % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end

            % Clear temporary variables
            clear name path;
        end

        % Button pushed function: BrowsePlannedButton
        function BrowsePlannedButtonPushed(app, event)
            
            % Request the user to select the planned dose image
             Event('UI window opened to select planned image file');
            [name, path] = uigetfile({'*.dxf', 'DXF File (*.dxf)'}, ...
                'Select Planned Open Field Image', app.path); %#ok<*ADPROPLC> 

            % If the user selected a file
            if ~isequal(name, 0)

                % Log event
                Event(['User selected ', fullfile(path, name)]);

                % Update default path
                Event(['Default file path updated to ', path]);
                app.path = path;

                % Set UI field with filename
                set(app.PlannedDoseImageEditField, 'Value', name);

                % Load file
                [~, targetResolution, targetImage] = LoadDXF(fullfile(path, name));

                % Extract radial profile
                [x, y] = pol2cart(repmat(pi/4, [1 size(targetImage,1)]), ...
                    0:size(targetImage,1)-1);
                x = x + size(targetImage,2)/2 + 0.5;
                y = y + size(targetImage,1)/2 + 0.5;
                app.targetProfile = zeros(size(targetImage,1), 2);
                app.targetProfile(:,2) = interp2(targetImage, x, y, 'linear', NaN);
                app.targetProfile(:,1) = (0:size(app.targetProfile,1)-1) * ...
                    targetResolution(1);

                % Calculate corrected profile, if able
                CalculateCorrectedProfile(app);

                % Update profile plot
                PlotNormalizedProfiles({app.currentCalibration, ...
                    app.targetProfile, ...
                    app.measuredProfile, ...
                    app.newProfile}, ...
                    app.UIAxes_2, 'first');
                set(app.UIAxes_2, 'Visible', true);
                legend(app.UIAxes_2, app.plotLegend, 'Location', 'southwest');

            % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end

            % Clear temporary variables
            clear name path targetImage targetResolution;
        end

        % Button pushed function: BrowseMeasuredButton
        function BrowseMeasuredButtonPushed(app, event)
            
            % Request the user to select the image file
            Event('UI window opened to select measured image file');
            [name, path] = uigetfile({'*.dxf', 'DXF File (*.dxf)'}, ...
                'Select Measured Open Field Image', app.path); %#ok<*ADPROPLC> 

            % If the user selected a file
            if ~isequal(name, 0)

                % Log event
                Event(['User selected ', fullfile(path, name)]);

                % Update default path
                Event(['Default file path updated to ', path]);
                app.path = path;

                % Set UI field with filename
                set(app.MeasuredImageEditField, 'Value', name);

                % Load image file
                [~, res, app.measuredImage] = LoadDXF(fullfile(path, name));

                % Clip image borders
                image = app.measuredImage(11:end-11, 11:end-11);

                % Find symmetric center profile of measured image
                [center, profile] = ImageSymmetricCenter(image);

                % Store profile
                app.measuredProfile = zeros(length(profile), 2);
                app.measuredProfile(:,2) = profile;
                app.measuredProfile(:,1) = (0:length(profile)-1) * res(1);

                % Calculate corrected profile, if able
                CalculateCorrectedProfile(app);

                % Update profile plot
                PlotNormalizedProfiles({app.currentCalibration, ...
                    app.targetProfile, ...
                    app.measuredProfile, ...
                    app.newProfile}, ...
                    app.UIAxes_2, 'first');
                set(app.UIAxes_2, 'Visible', true);
                legend(app.UIAxes_2, app.plotLegend, 'Location', 'southwest');

                % Plot measured image and center
                imagesc(app.UIAxes, app.measuredImage);
                xlim(app.UIAxes, [0 size(app.measuredImage, 2)-1]);
                ylim(app.UIAxes, [0 size(app.measuredImage, 1)-1]);
                set(app.UIAxes, 'yTickLabel', []);
                set(app.UIAxes, 'xTickLabel', []);
                set(app.UIAxes, 'Ticklength', [0 0]);
                colorbar(app.UIAxes, 'eastoutside');
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, center(2), center(1), 'b+');
                hold(app.UIAxes, 'off');
                set(app.UIAxes, 'Visible', true);

            % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end

            % Clear temporary variables
            clear name path res image center profile;
        end

        % Button pushed function: SaveNormalizationButton
        function SaveNormalizationButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Portal Dosimetry Profiler';
            app.Copyright = uilabel(app.UIFigure);
            app.Copyright.Text = 'Copyright (c) 2022 Aspirus Health. Authored by Mark Geurts';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Step1Label = uilabel(app.Panel_3);
            app.Step1Label.Text = 'Step 1: Measure open field';
            app.Step2Label = uilabel(app.Panel_3);
            app.Step2Label.Text = 'Step 2: Retrieve input files from system';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = 'Download the provided DICOM file and calculate the expected dose using your Portal Dosimetry model. Then deliver it on your machine to the MV panel.';
            app.DownloadDICOMButton = uibutton(app.Panel_3, 'push');
            app.DownloadDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDICOMButtonPushed, true);
            app.DownloadDICOMButton.Text = 'Download DICOM';
            app.Step2Instructions = uilabel(app.Panel_3);
            app.Step2Instructions.Text = 'Export the planned portal image dose and measured MV image from portal dosimetry as text (.dxf) files. Also retrieve the existing normalization profile used in PVA Calibration, in W2CAD (.cdp) format.';
            app.Step3Label = uilabel(app.Panel_3);
            app.Step3Label.Text = 'Step 3: Select the input files';
            app.NormalizationProfileEditFieldLabel = uilabel(app.Panel_3);
            app.NormalizationProfileEditFieldLabel.Text = 'Normalization Profile';
            app.NormalizationProfileEditField = uieditfield(app.Panel_3, 'text');
            app.NormalizationProfileEditField.Value = 'Choose a .cdp file';
            app.BrowseNormalizationButton = uibutton(app.Panel_3, 'push');
            app.BrowseNormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseNormalizationButtonPushed, true);
            app.BrowseNormalizationButton.Text = 'Browse';
            app.PlannedDoseImageEditFieldLabel = uilabel(app.Panel_3);
            app.PlannedDoseImageEditFieldLabel.Text = 'Planned Dose Image';
            app.PlannedDoseImageEditField = uieditfield(app.Panel_3, 'text');
            app.PlannedDoseImageEditField.Value = 'Choose a .dxf file';
            app.BrowsePlannedButton = uibutton(app.Panel_3, 'push');
            app.BrowsePlannedButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsePlannedButtonPushed, true);
            app.BrowsePlannedButton.Text = 'Browse';
            app.MeasuredImageEditFieldLabel = uilabel(app.Panel_3);
            app.MeasuredImageEditFieldLabel.Text = 'Measured Image';
            app.MeasuredImageEditField = uieditfield(app.Panel_3, 'text');
            app.MeasuredImageEditField.Value = 'Choose a .dxf file';
            app.BrowseMeasuredButton = uibutton(app.Panel_3, 'push');
            app.BrowseMeasuredButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMeasuredButtonPushed, true);
            app.BrowseMeasuredButton.Text = 'Browse';
            app.Step4Label = uilabel(app.Panel_3);
            app.Step4Label.Text = 'Step 4: Save Corrected Normalization';
            app.Step4Instructions = uilabel(app.Panel_3);
            app.Step4Instructions.Text = 'Save the new corrected normalization file and re-normalize your MV panel with it in PVA Calibration.';
            app.SaveNormalizationButton = uibutton(app.Panel_3, 'push');
            app.SaveNormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNormalizationButtonPushed, true);
            app.SaveNormalizationButton.Text = 'Save Normalization';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, 'Profiles')
            xlabel(app.UIAxes_2, 'Position (mm)')
            ylabel(app.UIAxes_2, 'Normalized Value')
            zlabel(app.UIAxes_2, 'Z')
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Text = 'Version 1.0.0';
            app.Panel = uipanel(app.UIFigure);
            app.ApplicationLogLabel = uilabel(app.Panel);
            app.ApplicationLogLabel.Text = 'Application Log';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDProfiler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end